classdef US_Atlas_Reg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TransferControlPointsButton  matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        AddcontrolpointButton        matlab.ui.control.Button
        Selectthe3DmaptoregisterrigidlyoptionalDropDown  matlab.ui.control.DropDown
        Selectthe3DmaptoregisterrigidlyoptionalDropDownLabel  matlab.ui.control.Label
        ManualrigidregistrationtoatlasButton  matlab.ui.control.Button
        Gammafor3DmaporthosliceviewerEditField  matlab.ui.control.NumericEditField
        Gammafor3DmaporthosliceviewerEditFieldLabel  matlab.ui.control.Label
        Selectthe3DmaptoregisternonlinearlyDropDown  matlab.ui.control.DropDown
        Selectthe3DmaptoregisternonlinearlyDropDownLabel  matlab.ui.control.Label
        SelecttheatlasDropDown       matlab.ui.control.DropDown
        SelecttheatlasDropDownLabel  matlab.ui.control.Label
        CtrlPtTable                  matlab.ui.control.Table
        OpenOrthoSliceViewerButton   matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        RunthenonlinearwarpingscatteredInterpolantTextArea  matlab.ui.control.TextArea
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenOrthoSliceViewerButton
        function OrthoSliceViewerButtonPushed(app, event)
            atlas = evalin('base', app.SelecttheatlasDropDown.Value);
            regmap = evalin('base', app.Selectthe3DmaptoregisternonlinearlyDropDown.Value); % Registered map
            if isstruct(regmap) % If the map is a structure (e.g., output of Medical Registration Estimator), extract the voxel information
                regmap = regmap.regVol.Voxels;
            end
            regmap = double(regmap);

            xhair_color = 'green';
            figure; atlasOSV = orthosliceViewer(atlas, 'CrosshairColor', xhair_color); % handle for the atlas' ortho slice viewer
            figure; mapOSV = orthosliceViewer(regmap .^ app.Gammafor3DmaporthosliceviewerEditField.Value, 'CrosshairColor', xhair_color); % handle for the map of interest's ortho slice viewer

            % ---- ADD LISTENER FUNCTION/CALLBACKS TO THE ORTHO SLICE
            % VIEWERS TO RECORD THE CROSSHAIR COORDINATES ----

            % atlasOSV_listener = addlistener(atlasOSV, 'CrosshairMoving', @getCrosshairLocation);
            % mapOSV_listener = addlistener(mapOSV, 'CrosshairMoved', @getCrosshairLocation);
            % 
            % % How do we store the return??????????????????
            % 
            % function xhl = getCrosshairLocation(src, evt)
            %     evname = evt.EventName;
            %     switch(evname)
            %         case{'CrosshairMoved'}
            %             xhl = evt.CurrentPosition;
            %         case{'CrosshairMoving'}
            %             xhl = evt.CurrentPosition;
            %     end
            % 
            % end
            % 
            % app.CtrlPtTable.Data{1} = xhl;
        end

        % Value changed function: 
        % Selectthe3DmaptoregisternonlinearlyDropDown
        function Selectthe3DmaptoregisternonlinearlyDropDownValueChanged(app, event)
            value = app.Selectthe3DmaptoregisternonlinearlyDropDown.Value; % Get the value of the drop-down, e.g., the 3D map to register to the atlas

            % ---- Clear the previous control points (or store somewhere else???) ----

            % ---- Update the real-time plot ----
            
        end

        % Drop down opening function: SelecttheatlasDropDown
        function SelecttheatlasDropDownOpening(app, event)
            % ----Update the choices when the dropdown is clicked ----
            allVars = evalin('base', 'who'); % Get all the variable names from the base workspace
            app.SelecttheatlasDropDown.Items = allVars;
        end

        % Drop down opening function: 
        % Selectthe3DmaptoregisternonlinearlyDropDown
        function Selectthe3DmaptoregisternonlinearlyDropDownOpening(app, event)
            % ----Update the choices when the dropdown is clicked ----
            allVars = evalin('base', 'who'); % Get all the variable names from the base workspace
            app.Selectthe3DmaptoregisternonlinearlyDropDown.Items = allVars;
        end

        % Callback function
        function SelecttheatlasDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.SelecttheatlasDropDown.Value = item; % Update the atlas variable to whatever is selected
        end

        % Callback function
        function Selectthe3DmaptoregisterDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.Selectthe3DmaptoregisternonlinearlyDropDown.Value = item;
        end

        % Button pushed function: ManualrigidregistrationtoatlasButton
        function ManualrigidregistrationtoatlasButtonPushed(app, event)

            % ---- Retrieve the atlas and initial 3D map to manually register ----
            atlas = evalin('base', app.SelecttheatlasDropDown.Value);
            map = evalin('base', app.Selectthe3DmaptoregisterrigidlyoptionalDropDown.Value);

            % ---- Permute if needed ----
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % ---- Prepare each medicalVolume ----
            atlas_R = medicalref3d(size(atlas)); % specify some properties of the atlas
            atlas_R.PatientCoordinateSystem = "LPS+";
            % atlas_R = imref3d(size(atlas)); % specify some properties of the atlas
            % atlas_R.voxelSpacing = ;

            map_R = medicalref3d(size(map));
            map_R.PatientCoordinateSystem = "LPS+";
            % map_R = imref3d(size(map));

            atlas_MV = medicalVolume(atlas, atlas_R);
            map_MV = medicalVolume(map, map_R);
            % map_MV = medicalVolume(map);

            % ---- Open Medical Registration Estimator ----
            medicalRegistrationEstimator(map_MV, atlas_MV); % Moving, Fixed
            % medicalRegistrationEstimator(atlas, map); % Need to convert to medical volumes first...

        end

        % Drop down opening function: 
        % Selectthe3DmaptoregisterrigidlyoptionalDropDown
        function Selectthe3DmaptoregisterrigidlyoptionalDropDownOpening(app, event)
            % ----Update the choices when the dropdown is clicked ----
            allVars = evalin('base', 'who'); % Get all the variable names from the base workspace
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDown.Items = allVars;
        end

        % Cell edit callback: CtrlPtTable
        function CtrlPtTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: AddcontrolpointButton
        function AddcontrolpointButtonPushed(app, event)
            % app.CtrlPtTable.RowName
            if isempty( app.CtrlPtTable.Data )
                 app.CtrlPtTable.Data = cell(1, 3);
            else
                app.CtrlPtTable.Data(end+1, :) = cell(1, 3);
            end
            % app.CtrlPtTable.Data{end+1, :} = [0, 0, 0];
            
            % post_table_size = size(app.CtrlPtTable.Data);
            % app.CtrlPtTable.Data(post_table_size(1), 1) = post_table_size(1); % update the index part
        end

        % Button pushed function: TransferControlPointsButton
        function TransferControlPointsButtonPushed(app, event)
            assignin('base', 'controlPoints', app.CtrlPtTable.Data)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {483, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenOrthoSliceViewerButton = uibutton(app.LeftPanel, 'push');
            app.OpenOrthoSliceViewerButton.ButtonPushedFcn = createCallbackFcn(app, @OrthoSliceViewerButtonPushed, true);
            app.OpenOrthoSliceViewerButton.Text = 'Open ortho slice viewer';
            app.CtrlPtTable = uitable(app.LeftPanel);
            app.CtrlPtTable.ColumnName = {'Index'; 'Ultrasound coordinates [x, y, z]'; 'Atlas coordinates [x, y, z]'};
            app.CtrlPtTable.ColumnEditable = true;
            app.CtrlPtTable.CellEditCallback = createCallbackFcn(app, @CtrlPtTableCellEdit, true);
            app.SelecttheatlasDropDownLabel = uilabel(app.LeftPanel);
            app.SelecttheatlasDropDownLabel.Text = 'Select the atlas';
            app.SelecttheatlasDropDown = uidropdown(app.LeftPanel);
            app.SelecttheatlasDropDown.Items = {};
            app.SelecttheatlasDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelecttheatlasDropDownOpening, true);
            app.SelecttheatlasDropDown.Value = {};
            app.Selectthe3DmaptoregisternonlinearlyDropDownLabel = uilabel(app.LeftPanel);
            app.Selectthe3DmaptoregisternonlinearlyDropDownLabel.Text = 'Select the 3D map to register nonlinearly';
            app.Selectthe3DmaptoregisternonlinearlyDropDown = uidropdown(app.LeftPanel);
            app.Selectthe3DmaptoregisternonlinearlyDropDown.Items = {''};
            app.Selectthe3DmaptoregisternonlinearlyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @Selectthe3DmaptoregisternonlinearlyDropDownOpening, true);
            app.Selectthe3DmaptoregisternonlinearlyDropDown.ValueChangedFcn = createCallbackFcn(app, @Selectthe3DmaptoregisternonlinearlyDropDownValueChanged, true);
            app.Selectthe3DmaptoregisternonlinearlyDropDown.Value = '';
            app.Gammafor3DmaporthosliceviewerEditFieldLabel = uilabel(app.LeftPanel);
            app.Gammafor3DmaporthosliceviewerEditFieldLabel.Text = 'Gamma for 3D map ortho slice viewer';
            app.Gammafor3DmaporthosliceviewerEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Gammafor3DmaporthosliceviewerEditField.Placeholder = '0.5';
            app.Gammafor3DmaporthosliceviewerEditField.Value = 0.5;
            app.ManualrigidregistrationtoatlasButton = uibutton(app.LeftPanel, 'push');
            app.ManualrigidregistrationtoatlasButton.ButtonPushedFcn = createCallbackFcn(app, @ManualrigidregistrationtoatlasButtonPushed, true);
            app.ManualrigidregistrationtoatlasButton.Text = 'Manual rigid registration to atlas';
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDownLabel = uilabel(app.LeftPanel);
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDownLabel.Text = 'Select the 3D map to register rigidly (optional)';
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDown = uidropdown(app.LeftPanel);
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDown.Items = {''};
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @Selectthe3DmaptoregisterrigidlyoptionalDropDownOpening, true);
            app.Selectthe3DmaptoregisterrigidlyoptionalDropDown.Value = '';
            app.AddcontrolpointButton = uibutton(app.LeftPanel, 'push');
            app.AddcontrolpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddcontrolpointButtonPushed, true);
            app.AddcontrolpointButton.Text = 'Add control point';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'(Open the ortho slice viewer and manually add control point coordinates)'};
            app.TransferControlPointsButton = uibutton(app.LeftPanel, 'push');
            app.TransferControlPointsButton.ButtonPushedFcn = createCallbackFcn(app, @TransferControlPointsButtonPushed, true);
            app.TransferControlPointsButton.Text = 'Transfer control points to workspace';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RunthenonlinearwarpingscatteredInterpolantTextArea = uitextarea(app.CenterPanel);
            app.RunthenonlinearwarpingscatteredInterpolantTextArea.Value = {'Run the nonlinear warping (scatteredInterpolant)'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = US_Atlas_Reg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end